classdef egaliseur_delevaux_jeanson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        SelectionTab                matlab.ui.container.Tab
        ChercherfichierButton       matlab.ui.control.Button
        TextArea_2                  matlab.ui.control.TextArea
        EditField_7                 matlab.ui.control.NumericEditField
        GainglobalSlider            matlab.ui.control.Slider
        GainglobalSliderLabel       matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        EditField_6                 matlab.ui.control.NumericEditField
        EditField_5                 matlab.ui.control.NumericEditField
        EditField_4                 matlab.ui.control.NumericEditField
        EditField_3                 matlab.ui.control.NumericEditField
        EditField_2                 matlab.ui.control.NumericEditField
        EditField_1                 matlab.ui.control.NumericEditField
        hautefrquenceSlider         matlab.ui.control.Slider
        hautefrquenceSliderLabel    matlab.ui.control.Label
        Slider2_7                   matlab.ui.control.Slider
        JouerStopButton             matlab.ui.control.Button
        Slider2_6                   matlab.ui.control.Slider
        Slider2_5                   matlab.ui.control.Slider
        Slider2_4                   matlab.ui.control.Slider
        Slider2_3                   matlab.ui.control.Slider
        Slider2_2                   matlab.ui.control.Slider
        bassefrquenceSlider         matlab.ui.control.Slider
        bassefrquenceSliderLabel    matlab.ui.control.Label
        NomdufichierEditField       matlab.ui.control.EditField
        NomdufichierEditFieldLabel  matlab.ui.control.Label
        ChoixdufiltreDropDown       matlab.ui.control.DropDown
        ChoixdufiltreDropDownLabel  matlab.ui.control.Label
        MisejourButton              matlab.ui.control.Button
        AffichageTab                matlab.ui.container.Tab
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        y
        yfiltre
        Fs
        x=0
        info
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MisejourButton
        function MisejourButtonPushed(app, event)

            t = 0:seconds(1/app.Fs):seconds(app.info.Duration);
            t = t(1:end-1);

            plot(app.UIAxes,t,app.y)
            title(app.UIAxes,"Représentation temporelle du signal original");
            xlabel(app.UIAxes,"Temps (s)");
            ylabel(app.UIAxes,"Amplitude");


            %Fourier=============================================================
            FOURIER=fft(app.y(:,1));
            ly = length(FOURIER); % Taille du vecteur y
            z = fftshift(FOURIER); % Réorganisation de la FFT
            f = (-ly/2:ly/2-1)/ly*app.Fs;

            spectrepuissance=10*log((abs(z).^2)/app.info.Duration);
            plot(app.UIAxes2,f,spectrepuissance)
            xlabel(app.UIAxes2,'Fréquence')
            ylabel(app.UIAxes2,'Puissance en dB')
            title(app.UIAxes2,"Spectre original")
            %Filtre ===========================================
            %mettre bilinéaire pour bessel
            n=5;
            F_audible=20000;

            puissanceChercheeDB = -3;
            puissanceCherchee=10*log((abs(puissanceChercheeDB)^2)/app.info.Duration);
            switch app.ChoixdufiltreDropDown.Value
                case 'Bessel'
                    [b, a] = besself(n, 2*pi*app.EditField_1.Value,'low');
                    [b, a]=bilinear(b,a,app.Fs);%permet de passer d'analogique à digital
                    canal1 = filter (b,a,app.y)* db2pow(app.bassefrquenceSlider.Value);

                    [b, a] = besself(n, 2*pi*[app.EditField_1.Value , app.EditField_2.Value],'bandpass');
                    [b, a]=bilinear(b,a,app.Fs);
                    canal2 = filter (b,a,app.y)* db2pow(app.Slider2_2.Value);

                    [b, a] = besself(n, 2*pi*[app.EditField_2.Value , app.EditField_3.Value],'bandpass');
                    [b, a]=bilinear(b,a,app.Fs);
                    canal3 = filter (b,a,app.y)* db2pow(app.Slider2_3.Value);

                    [b, a] = besself(n, 2*pi*[app.EditField_3.Value , app.EditField_4.Value],'bandpass');
                    [b, a]=bilinear(b,a,app.Fs);
                    canal4 = filter (b,a,app.y)* db2pow(app.Slider2_4.Value);

                    [b, a] = besself(n, 2*pi*[app.EditField_4.Value , app.EditField_5.Value],'bandpass');
                    [b, a]=bilinear(b,a,app.Fs);
                    canal5 = filter (b,a,app.y)* db2pow(app.Slider2_5.Value);

                    [b, a] = besself(n, 2*pi*[app.EditField_5.Value , app.EditField_6.Value],'bandpass');
                    [b, a]=bilinear(b,a,app.Fs);
                    canal6 = filter (b,a,app.y)* db2pow(app.Slider2_6.Value);

                    [b, a] = besself(n, 2*pi*[app.EditField_6.Value , app.EditField_7.Value],'bandpass');
                    [b, a]=bilinear(b,a,app.Fs);
                    canal7 = filter (b,a,app.y)* db2pow(app.Slider2_7.Value);

                    [b, a] = besself(n, 2*pi*app.EditField_7.Value,'high');
                    [b, a]=bilinear(b,a,app.Fs);
                    canal8 = filter (b,a,app.y)* db2pow(app.hautefrquenceSlider.Value);

                case 'Butterworth'

                    % Canal 1
                    [b, a] = butter(n, app.EditField_1.Value/F_audible,'low');
                    premFiltre = filter (b,a,app.y);
                    premFiltreFFT=fft(premFiltre);
                    indice = find((premFiltreFFT<puissanceCherchee),1);
                    [b, a] = butter(n,(app.EditField_1.Value-indice)/F_audible,'low');
                    canal1 = filter(b,a,app.y) * db2pow(app.bassefrquenceSlider.Value);


                    % Canal 2
                    [b, a] = butter(n, [app.EditField_1.Value , app.EditField_2.Value]/F_audible,'bandpass');

                    premFiltre = filter (b,a,app.y);
                    premFiltreFFT=fft(premFiltre);
                    indice = find((premFiltreFFT>puissanceCherchee),1);
                    [b, a] = butter(n, [app.EditField_1.Value+indice , app.EditField_2.Value-indice]/F_audible,'bandpass');
                    canal2 = filter (b,a,app.y)* db2pow(app.Slider2_2.Value);


                    % Canal 3
                    [b, a] = butter(n, [app.EditField_2.Value , app.EditField_3.Value]/F_audible,'bandpass');

                    premFiltre = filter (b,a,app.y);
                    premFiltreFFT=fft(premFiltre);
                    indice = find((premFiltreFFT>puissanceCherchee),1);
                    [b, a] = butter(n, [app.EditField_2.Value+indice , app.EditField_3.Value-indice]/F_audible,'bandpass');
                    canal3 = filter (b,a,app.y)* db2pow(app.Slider2_3.Value);


                    % Canal 4
                    [b, a] = butter(n, [app.EditField_3.Value , app.EditField_4.Value]/F_audible,'bandpass');

                    premFiltre = filter (b,a,app.y);
                    premFiltreFFT=fft(premFiltre);
                    indice = find((premFiltreFFT>puissanceCherchee),1);
                    [b, a] = butter(n, [app.EditField_3.Value+indice , app.EditField_4.Value-indice]/F_audible,'bandpass');
                    canal4 = filter (b,a,app.y)* db2pow(app.Slider2_4.Value);


                    % Canal 5
                    [b, a] = butter(n, [app.EditField_4.Value , app.EditField_5.Value]/F_audible,'bandpass');

                    premFiltre = filter (b,a,app.y);
                    premFiltreFFT=fft(premFiltre);
                    indice = find((premFiltreFFT>puissanceCherchee),1);
                    [b, a] = butter(2*n, [app.EditField_4.Value+indice , app.EditField_5.Value-indice]/F_audible,'bandpass');
                    canal5 = filter (b,a,app.y)* db2pow(app.Slider2_5.Value);


                    % Canal 6
                    [b, a] = butter(n, [app.EditField_5.Value , app.EditField_6.Value]/F_audible,'bandpass');

                    premFiltre = filter (b,a,app.y);
                    premFiltreFFT=fft(premFiltre);
                    indice = find((premFiltreFFT>puissanceCherchee),1);
                    [b, a] = butter(n, [app.EditField_5.Value+indice , app.EditField_6.Value-indice]/F_audible,'bandpass');
                    canal6 = filter (b,a,app.y)* db2pow(app.Slider2_6.Value);


                    % Canal 7
                    [b, a] = butter(n, [app.EditField_6.Value , app.EditField_7.Value]/F_audible,'bandpass');

                    premFiltre = filter (b,a,app.y);
                    premFiltreFFT=fft(premFiltre);
                    indice = find((premFiltreFFT>puissanceCherchee),1);
                    [b, a] = butter(n, [app.EditField_6.Value+indice , app.EditField_7.Value-indice]/F_audible,'bandpass');
                    canal7 = filter (b,a,app.y)* db2pow(app.Slider2_7.Value);


                    % Canal 8
                    [b, a] = butter(n, app.EditField_7.Value/F_audible,'high');

                    premFiltre = filter (b,a,app.y);
                    premFiltreFFT=fft(premFiltre);
                    indice = find((premFiltreFFT>puissanceCherchee),1);
                    [b, a] = butter(n, (app.EditField_7.Value-indice)/F_audible,'high');
                    canal8 = filter (b,a,app.y)* db2pow(app.hautefrquenceSlider.Value);

                case 'Tchebychev 1'
                    Rp=10;

                    [b, a] = cheby1(n, Rp, app.EditField_1.Value/F_audible,'low');
                    canal1 = filter (b,a,app.y)* db2pow(app.bassefrquenceSlider.Value);

                    [b, a] = cheby1(n, Rp, [app.EditField_1.Value , app.EditField_2.Value]/F_audible,'bandpass');
                    canal2 = filter (b,a,app.y)* db2pow(app.Slider2_2.Value);

                    [b, a] = cheby1(n, Rp,[app.EditField_2.Value , app.EditField_3.Value]/F_audible,'bandpass');
                    canal3 = filter (b,a,app.y)* db2pow(app.Slider2_3.Value);

                    [b, a] = cheby1(n, Rp, [app.EditField_3.Value , app.EditField_4.Value]/F_audible,'bandpass');
                    canal4 = filter (b,a,app.y)* db2pow(app.Slider2_4.Value);

                    [b, a] = cheby1(n, Rp, [app.EditField_4.Value , app.EditField_5.Value]/F_audible,'bandpass');
                    canal5 = filter (b,a,app.y)* db2pow(app.Slider2_5.Value);

                    [b, a] = cheby1(n, Rp, [app.EditField_5.Value , app.EditField_6.Value]/F_audible,'bandpass');
                    canal6 = filter (b,a,app.y)* db2pow(app.Slider2_6.Value);

                    [b, a] = cheby1(n, Rp, [app.EditField_6.Value , app.EditField_7.Value]/F_audible,'bandpass');
                    canal7 = filter (b,a,app.y)* db2pow(app.Slider2_7.Value);

                    [b, a] = cheby1(n, Rp, app.EditField_7.Value/F_audible,'high');
                    canal8 = filter (b,a,app.y)* db2pow(app.hautefrquenceSlider.Value);
                case 'Tchebychev 2'
                    Rs=10;

                    [b, a] = cheby2(n, Rs, app.EditField_1.Value/F_audible,'low');
                    canal1 = filter (b,a,app.y)* db2pow(app.bassefrquenceSlider.Value);

                    [b, a] = cheby2(n, Rs, [app.EditField_1.Value , app.EditField_2.Value]/F_audible,'bandpass');
                    canal2 = filter (b,a,app.y)* db2pow(app.Slider2_2.Value);

                    [b, a] = cheby2(n, Rs,[app.EditField_2.Value , app.EditField_3.Value]/F_audible,'bandpass');
                    canal3 = filter (b,a,app.y)* db2pow(app.Slider2_3.Value);

                    [b, a] = cheby2(n, Rs, [app.EditField_3.Value , app.EditField_4.Value]/F_audible,'bandpass');
                    canal4 = filter (b,a,app.y)* db2pow(app.Slider2_4.Value);

                    [b, a] = cheby2(n, Rs, [app.EditField_4.Value , app.EditField_5.Value]/F_audible,'bandpass');
                    canal5 = filter (b,a,app.y)* db2pow(app.Slider2_5.Value);

                    [b, a] = cheby2(n, Rs, [app.EditField_5.Value , app.EditField_6.Value]/F_audible,'bandpass');
                    canal6 = filter (b,a,app.y)* db2pow(app.Slider2_6.Value);

                    [b, a] = cheby2(n, Rs, [app.EditField_6.Value , app.EditField_7.Value]/F_audible,'bandpass');
                    canal7 = filter (b,a,app.y)* db2pow(app.Slider2_7.Value);

                    [b, a] = cheby2(n, Rs, app.EditField_7.Value/F_audible,'high');
                    canal8 = filter (b,a,app.y)*db2pow(app.hautefrquenceSlider.Value);

            end

            app.yfiltre = (canal1 + canal2 + canal3 + canal4 + canal5 + canal6 + canal7 + canal8)* db2pow(app.GainglobalSlider.Value);

            hold(app.UIAxes,"on");
            hold(app.UIAxes2,"on");
            plot(app.UIAxes,t,app.yfiltre);
            title(app.UIAxes,"Représentation temporelle du signal filtré");
            xlabel(app.UIAxes,"Temps (s)");
            ylabel(app.UIAxes,"Amplitude");


            FOURIER_filtre=fft(app.yfiltre);
            ly = length(FOURIER_filtre); % Taille du vecteur y
            zfiltre = fftshift(FOURIER_filtre); % Réorganisation de la FFT

            spectrepuissancefiltre=10*log((abs(zfiltre).^2)/app.info.Duration);
            plot(app.UIAxes2,f,spectrepuissancefiltre);
            xlabel(app.UIAxes2,'Fréquence');
            ylabel(app.UIAxes2,'Puissance en dB');
            title(app.UIAxes2,"Spectre après filtrage ");


            hold(app.UIAxes,"off");
            hold(app.UIAxes2,"off");

        end

        % Button pushed function: JouerStopButton
        function JouerStopButtonPushed(app, event)
            if (app.x==0)
                sound(app.yfiltre,app.Fs);
                app.x=1;
            else
                clear sound;
                app.x=0;
            end
        end

        % Button pushed function: ChercherfichierButton
        function ChercherfichierButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.wav');

            if filename == 0
                app.y = [];
                app.Fs = [];
            else
                app.info = audioinfo(fullfile(pathname, filename));
                [app.y,app.Fs] = audioread(fullfile(pathname, filename));
            end
            app.NomdufichierEditField.Value= filename;
            app.y=app.y(:,1);%si multicanal, pas de probleme
            app.yfiltre=app.y; %permet de conserver le signal original

            app.JouerStopButton.Enable = "on"; %permet d'activer les boutons qui sont désactivés au demarrage
            app.MisejourButton.Enable="on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectionTab = uitab(app.TabGroup);
            app.SelectionTab.Title = 'Selection';
            app.MisejourButton = uibutton(app.SelectionTab, 'push');
            app.MisejourButton.ButtonPushedFcn = createCallbackFcn(app, @MisejourButtonPushed, true);
            app.MisejourButton.Enable = 'off';
            app.MisejourButton.Text = 'Mise à jour';
            app.ChoixdufiltreDropDownLabel = uilabel(app.SelectionTab);
            app.ChoixdufiltreDropDownLabel.Text = 'Choix du filtre';
            app.ChoixdufiltreDropDown = uidropdown(app.SelectionTab);
            app.ChoixdufiltreDropDown.Items = {'Butterworth', 'Bessel', 'Tchebychev 1', 'Tchebychev 2'};
            app.ChoixdufiltreDropDown.Value = 'Butterworth';
            app.NomdufichierEditFieldLabel = uilabel(app.SelectionTab);
            app.NomdufichierEditFieldLabel.Text = 'Nom du fichier';
            app.NomdufichierEditField = uieditfield(app.SelectionTab, 'text');
            app.bassefrquenceSliderLabel = uilabel(app.SelectionTab);
            app.bassefrquenceSliderLabel.Text = 'basse fréquence';
            app.bassefrquenceSlider = uislider(app.SelectionTab);
            app.bassefrquenceSlider.Limits = [-50 10];
            app.Slider2_2 = uislider(app.SelectionTab);
            app.Slider2_2.Limits = [-50 10];
            app.Slider2_3 = uislider(app.SelectionTab);
            app.Slider2_3.Limits = [-50 10];
            app.Slider2_4 = uislider(app.SelectionTab);
            app.Slider2_4.Limits = [-50 10];
            app.Slider2_5 = uislider(app.SelectionTab);
            app.Slider2_5.Limits = [-50 10];
            app.Slider2_6 = uislider(app.SelectionTab);
            app.Slider2_6.Limits = [-50 10];
            app.JouerStopButton = uibutton(app.SelectionTab, 'push');
            app.JouerStopButton.ButtonPushedFcn = createCallbackFcn(app, @JouerStopButtonPushed, true);
            app.JouerStopButton.Enable = 'off';
            app.JouerStopButton.Text = 'Jouer/Stop';
            app.Slider2_7 = uislider(app.SelectionTab);
            app.Slider2_7.Limits = [-50 10];
            app.hautefrquenceSliderLabel = uilabel(app.SelectionTab);
            app.hautefrquenceSliderLabel.Text = 'haute fréquence';
            app.hautefrquenceSlider = uislider(app.SelectionTab);
            app.hautefrquenceSlider.Limits = [-50 10];
            app.EditField_1 = uieditfield(app.SelectionTab, 'numeric');
            app.EditField_1.Value = 2500;
            app.EditField_2 = uieditfield(app.SelectionTab, 'numeric');
            app.EditField_2.Value = 5000;
            app.EditField_3 = uieditfield(app.SelectionTab, 'numeric');
            app.EditField_3.Value = 7500;
            app.EditField_4 = uieditfield(app.SelectionTab, 'numeric');
            app.EditField_4.Value = 10000;
            app.EditField_5 = uieditfield(app.SelectionTab, 'numeric');
            app.EditField_5.Value = 12500;
            app.EditField_6 = uieditfield(app.SelectionTab, 'numeric');
            app.EditField_6.Value = 15000;
            app.TextArea = uitextarea(app.SelectionTab);
            app.TextArea.Value = {'Choisisser les limites des bandes de fréquence du signal audio. Les valeurs sont deja defini pour les fréquences aux limites qui utilisent des filtres passes haut et passe bas. '; 'Ensuite, pour chaque canal, choisisez le gain que vous souhaiter appliquer'};
            app.GainglobalSliderLabel = uilabel(app.SelectionTab);
            app.GainglobalSliderLabel.Text = 'Gain global';
            app.GainglobalSlider = uislider(app.SelectionTab);
            app.GainglobalSlider.Limits = [-20 20];
            app.EditField_7 = uieditfield(app.SelectionTab, 'numeric');
            app.EditField_7.Value = 17500;
            app.TextArea_2 = uitextarea(app.SelectionTab);
            app.TextArea_2.Value = {'Fréquence en Hz :'};
            app.ChercherfichierButton = uibutton(app.SelectionTab, 'push');
            app.ChercherfichierButton.ButtonPushedFcn = createCallbackFcn(app, @ChercherfichierButtonPushed, true);
            app.ChercherfichierButton.Text = 'Chercher fichier';
            app.AffichageTab = uitab(app.TabGroup);
            app.AffichageTab.Title = 'Affichage';
            app.UIAxes = uiaxes(app.AffichageTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.AffichageTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = egaliseur_delevaux_jeanson

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end