classdef mixeur < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SlectionfichierTab              matlab.ui.container.Tab
        TextArea_2                      matlab.ui.control.TextArea
        NombredecanauxEditField_2       matlab.ui.control.NumericEditField
        NombredecanauxEditField_2Label  matlab.ui.control.Label
        ModifierlaslectionButton        matlab.ui.control.Button
        ValiderlaslectionButton         matlab.ui.control.Button
        Nom5EditField                   matlab.ui.control.EditField
        Nom5EditFieldLabel              matlab.ui.control.Label
        Nom4EditField                   matlab.ui.control.EditField
        Nom4EditFieldLabel              matlab.ui.control.Label
        Nom3EditField                   matlab.ui.control.EditField
        Nom3EditFieldLabel              matlab.ui.control.Label
        Nom2EditField                   matlab.ui.control.EditField
        Nom2EditFieldLabel              matlab.ui.control.Label
        Nom1EditField                   matlab.ui.control.EditField
        Nom1EditFieldLabel              matlab.ui.control.Label
        Fichier5Button                  matlab.ui.control.Button
        Fichier4Button                  matlab.ui.control.Button
        Fichier3Button                  matlab.ui.control.Button
        Fichier2Button                  matlab.ui.control.Button
        Fichier1Button                  matlab.ui.control.Button
        SelectionvolumeTab              matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        Volumefichier3Slider            matlab.ui.control.Slider
        Volumefichier3SliderLabel       matlab.ui.control.Label
        Volumefichier4Slider            matlab.ui.control.Slider
        Volumefichier4SliderLabel       matlab.ui.control.Label
        Volumefichier5Slider            matlab.ui.control.Slider
        Volumefichier5SliderLabel       matlab.ui.control.Label
        Volumefichier2Slider            matlab.ui.control.Slider
        Volumefichier2SliderLabel       matlab.ui.control.Label
        Volumefichier1Slider            matlab.ui.control.Slider
        Volumefichier1SliderLabel       matlab.ui.control.Label
        AppliquerButton                 matlab.ui.control.Button
        CanalnEditField                 matlab.ui.control.NumericEditField
        CanalnEditFieldLabel            matlab.ui.control.Label
        SignaldesortieTab               matlab.ui.container.Tab
        ArreterButton                   matlab.ui.control.Button
        JouerButton                     matlab.ui.control.Button
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        y1og, y2og, y3og, y4og, y5og
        y1, y2, y3, y4, y5
        Fs
        lengthmax
        nb_canaux
        i=1;
        yfinal
    end

    methods (Access = private)

        function [y,Fs,filename] = fichieraudio(app)
            [filename, pathname] = uigetfile('*.wav');
            if filename == 0
                y = [];
                Fs = [];
            else
                [y,Fs] = audioread(fullfile(pathname, filename));
                y=y(:,1); %on recupere qu'un seul canal
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ValiderlaslectionButton
        function ValiderlaslectionButtonPushed(app, event)
            %permet de conserver les fichiers audio originaux en cas de
            %modification d'un seul fichier
            app.y1=app.y1og;
            app.y2=app.y2og;
            app.y3=app.y3og;
            app.y4=app.y4og;
            app.y5=app.y5og;

            app.ModifierlaslectionButton.Enable ="on";
            app.ValiderlaslectionButton.Enable="off";
            app.Nom1EditField.Enable="off";
            app.Nom2EditField.Enable="off";
            app.Nom3EditField.Enable="off";
            app.Nom4EditField.Enable="off";
            app.Nom5EditField.Enable="off";
            app.JouerButton.Enable="off";

            app.i=1;%on initialise le canal
            app.CanalnEditField.Value=app.i;
            app.nb_canaux = app.NombredecanauxEditField_2.Value;
            app.AppliquerButton.Enable="on";

            %mise sous la meme taille
            app.lengthmax=max([length(app.y1), length(app.y2), length(app.y3), length(app.y4), length(app.y5)]);

            app.y1=[app.y1; zeros(app.lengthmax-length(app.y1),1)];
            app.y2=[app.y2; zeros(app.lengthmax-length(app.y2),1)];
            app.y3=[app.y3; zeros(app.lengthmax-length(app.y3),1)];
            app.y4=[app.y4; zeros(app.lengthmax-length(app.y4),1)];
            app.y5=[app.y5; zeros(app.lengthmax-length(app.y5),1)];

            %Affichage
            t =(1:app.lengthmax)/app.Fs;

            FOURIER1=fft(app.y1);
            FOURIER2=fft(app.y2);
            FOURIER3=fft(app.y3);
            FOURIER4=fft(app.y4);
            FOURIER5=fft(app.y5);

            ly = length(FOURIER5); % Taille du vecteur

            z1 = fftshift(FOURIER1); % Réorganisation de la FFT
            z2 = fftshift(FOURIER2);
            z3 = fftshift(FOURIER3);
            z4 = fftshift(FOURIER4);
            z5 = fftshift(FOURIER5);
            f = (-ly/2:ly/2-1)/ly*app.Fs;
            %creation de la figure

            figure
            affichageaudio = tiledlayout(5,2); %definit le nombre de diagramme
            title(affichageaudio,"Affichage des signaux originaux")

            function [] = affichage(y ,z ,val)
                nexttile;
                plot(t, y);
                title("Evolution temporelle du signal " + val);
                xlabel("Temps (s)");
                ylabel("Amplitude");
                nexttile;
                plot(f,z);
                title("Evolution fréquentielle du signal "+ val);
                xlabel("Fréquence");
                ylabel("Amplitude");
            end

            affichage(app.y1, z1, 1);
            affichage(app.y2, z2, 2);
            affichage(app.y3, z3, 3);
            affichage(app.y4, z4, 4);
            affichage(app.y5, z5, 5);
        end

        % Button pushed function: ModifierlaslectionButton
        function ModifierlaslectionButtonPushed(app, event)
            app.ModifierlaslectionButton.Enable ="off";
            app.ValiderlaslectionButton.Enable="on";
            app.Nom1EditField.Enable="on";
            app.Nom2EditField.Enable="on";
            app.Nom3EditField.Enable="on";
            app.Nom4EditField.Enable="on";
            app.Nom5EditField.Enable="on";
        end

        % Button pushed function: AppliquerButton
        function AppliquerButtonPushed(app, event)
            %indice de debut et de fin
            indice1 = (app.i-1)*app.lengthmax/app.nb_canaux +1;
            indice2 = app.i*app.lengthmax/app.nb_canaux;
            %application volume
            app.y1(indice1:indice2) = app.y1(indice1:indice2)*app.Volumefichier1Slider.Value/100;
            app.y2(indice1:indice2) = app.y2(indice1:indice2)*app.Volumefichier2Slider.Value/100;
            app.y3(indice1:indice2) = app.y3(indice1:indice2)*app.Volumefichier3Slider.Value/100;
            app.y4(indice1:indice2) = app.y4(indice1:indice2)*app.Volumefichier4Slider.Value/100;
            app.y5(indice1:indice2) = app.y5(indice1:indice2)*app.Volumefichier5Slider.Value/100;

            %condition fin boucle
            if (app.i>=app.nb_canaux)
                app.AppliquerButton.Enable="off";
                %addition des signaux
                app.yfinal = app.y1 + app.y2 + app.y3 + app.y4 + app.y5;
                app.JouerButton.Enable="on";
                %représentation temporelle
                t =(1:app.lengthmax)/app.Fs;
                plot(app.UIAxes3,t,app.yfinal);
                title(app.UIAxes3,"Evolution temporelle du signal mixé");
                xlabel(app.UIAxes3, "Temps (s)");
                ylabel(app.UIAxes3, "Amplitude");
                %spectre

                FOURIER=fft(app.yfinal);
                ly = length(FOURIER); % Taille du vecteur y
                z = fftshift(FOURIER); % Réorganisation de la FFT
                f = (-ly/2:ly/2-1)/ly*app.Fs;
                spectrepuissance=10*log((abs(z).^2)/(app.lengthmax/app.Fs));
                plot(app.UIAxes3_2, f,spectrepuissance)
                title(app.UIAxes3_2,"Spectre du signal mixé");
                xlabel(app.UIAxes3_2, "Fréquence");
                ylabel(app.UIAxes3_2, "Puissance (dB)");

            end

            %passage boucle suivante
            app.i= app.i +1;
            app.CanalnEditField.Value=app.i;
        end

        % Button pushed function: Fichier1Button
        function Fichier1ButtonPushed(app, event)
            [app.y1og,app.Fs,filename] = app.fichieraudio();
            app.Nom1EditField.Value= filename;
        end

        % Button pushed function: Fichier2Button
        function Fichier2ButtonPushed(app, event)
            [app.y2og,app.Fs,filename] = app.fichieraudio();
            app.Nom2EditField.Value= filename;
        end

        % Button pushed function: Fichier3Button
        function Fichier3ButtonPushed(app, event)
            [app.y3og,app.Fs,filename] = app.fichieraudio();
            app.Nom3EditField.Value= filename;
        end

        % Button pushed function: Fichier4Button
        function Fichier4ButtonPushed(app, event)
            [app.y4og,app.Fs,filename] = app.fichieraudio();
            app.Nom4EditField.Value= filename;
        end

        % Button pushed function: Fichier5Button
        function Fichier5ButtonPushed(app, event)
            [app.y5og,app.Fs,filename] = app.fichieraudio();
            app.Nom5EditField.Value= filename;
        end

        % Button pushed function: ArreterButton
        function ArreterButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: JouerButton
        function JouerButtonPushed(app, event)
            sound (app.yfinal, app.Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SlectionfichierTab = uitab(app.TabGroup);
            app.SlectionfichierTab.Title = 'Sélection fichier';
            app.Fichier1Button = uibutton(app.SlectionfichierTab, 'push');
            app.Fichier1Button.ButtonPushedFcn = createCallbackFcn(app, @Fichier1ButtonPushed, true);
            app.Fichier1Button.Text = 'Fichier 1';
            app.Fichier2Button = uibutton(app.SlectionfichierTab, 'push');
            app.Fichier2Button.ButtonPushedFcn = createCallbackFcn(app, @Fichier2ButtonPushed, true);
            app.Fichier2Button.Text = 'Fichier 2';
            app.Fichier3Button = uibutton(app.SlectionfichierTab, 'push');
            app.Fichier3Button.ButtonPushedFcn = createCallbackFcn(app, @Fichier3ButtonPushed, true);
            app.Fichier3Button.Text = 'Fichier 3';
            app.Fichier4Button = uibutton(app.SlectionfichierTab, 'push');
            app.Fichier4Button.ButtonPushedFcn = createCallbackFcn(app, @Fichier4ButtonPushed, true);
            app.Fichier4Button.Text = 'Fichier 4';
            app.Fichier5Button = uibutton(app.SlectionfichierTab, 'push');
            app.Fichier5Button.ButtonPushedFcn = createCallbackFcn(app, @Fichier5ButtonPushed, true);
            app.Fichier5Button.Text = 'Fichier 5';
            app.Nom1EditFieldLabel = uilabel(app.SlectionfichierTab);
            app.Nom1EditFieldLabel.Text = 'Nom 1:';
            app.Nom1EditField = uieditfield(app.SlectionfichierTab, 'text');
            app.Nom2EditFieldLabel = uilabel(app.SlectionfichierTab);
            app.Nom2EditFieldLabel.Text = 'Nom 2:';
            app.Nom2EditField = uieditfield(app.SlectionfichierTab, 'text');
            app.Nom3EditFieldLabel = uilabel(app.SlectionfichierTab);
            app.Nom3EditFieldLabel.Text = 'Nom 3:';
            app.Nom3EditField = uieditfield(app.SlectionfichierTab, 'text');
            app.Nom4EditFieldLabel = uilabel(app.SlectionfichierTab);
            app.Nom4EditFieldLabel.Text = 'Nom 4:';
            app.Nom4EditField = uieditfield(app.SlectionfichierTab, 'text');
            app.Nom5EditFieldLabel = uilabel(app.SlectionfichierTab);
            app.Nom5EditFieldLabel.Text = 'Nom 5:';
            app.Nom5EditField = uieditfield(app.SlectionfichierTab, 'text');
            app.ValiderlaslectionButton = uibutton(app.SlectionfichierTab, 'push');
            app.ValiderlaslectionButton.ButtonPushedFcn = createCallbackFcn(app, @ValiderlaslectionButtonPushed, true);
            app.ValiderlaslectionButton.Text = 'Valider la sélection';
            app.ModifierlaslectionButton = uibutton(app.SlectionfichierTab, 'push');
            app.ModifierlaslectionButton.ButtonPushedFcn = createCallbackFcn(app, @ModifierlaslectionButtonPushed, true);
            app.ModifierlaslectionButton.Enable = 'off';
            app.ModifierlaslectionButton.Text = 'Modifier la sélection';
            app.NombredecanauxEditField_2Label = uilabel(app.SlectionfichierTab);
            app.NombredecanauxEditField_2Label.Text = 'Nombre de canaux';
            app.NombredecanauxEditField_2 = uieditfield(app.SlectionfichierTab, 'numeric');
            app.NombredecanauxEditField_2.Limits = [1 Inf];
            app.NombredecanauxEditField_2.Value = 1;
            app.TextArea_2 = uitextarea(app.SlectionfichierTab);
            app.TextArea_2.Value = {'Sélectionnez vos fichiers audios et le nombre de canaux que vous souhaitez créer pour decouper votre son. Appuyez sur Valider.'; ''; 'Une fenetre s''ouvrira pour afficher la représentation des differents signaux.'; ''; 'Passer ensuite sur la partie selection volume pour gérer le mixage de vos canaux. '; ''; 'Ensuite, vous pourrez voir le résultat et le jouer dans la section "Signal de sortie".'};
            app.SelectionvolumeTab = uitab(app.TabGroup);
            app.SelectionvolumeTab.Title = 'Selection volume';
            app.CanalnEditFieldLabel = uilabel(app.SelectionvolumeTab);
            app.CanalnEditFieldLabel.Text = 'Canal n°';
            app.CanalnEditField = uieditfield(app.SelectionvolumeTab, 'numeric');
            app.CanalnEditField.Limits = [1 Inf];
            app.CanalnEditField.Value = 1;
            app.AppliquerButton = uibutton(app.SelectionvolumeTab, 'push');
            app.AppliquerButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButtonPushed, true);
            app.AppliquerButton.Enable = 'off';
            app.AppliquerButton.Text = 'Appliquer';
            app.Volumefichier1SliderLabel = uilabel(app.SelectionvolumeTab);
            app.Volumefichier1SliderLabel.Text = 'Volume fichier 1';
            app.Volumefichier1Slider = uislider(app.SelectionvolumeTab);
            app.Volumefichier2SliderLabel = uilabel(app.SelectionvolumeTab);
            app.Volumefichier2SliderLabel.Text = 'Volume fichier 2';
            app.Volumefichier2Slider = uislider(app.SelectionvolumeTab);
            app.Volumefichier5SliderLabel = uilabel(app.SelectionvolumeTab);
            app.Volumefichier5SliderLabel.Text = 'Volume fichier 5';
            app.Volumefichier5Slider = uislider(app.SelectionvolumeTab);
            app.Volumefichier4SliderLabel = uilabel(app.SelectionvolumeTab);
            app.Volumefichier4SliderLabel.Text = 'Volume fichier 4';
            app.Volumefichier4Slider = uislider(app.SelectionvolumeTab);
            app.Volumefichier3SliderLabel = uilabel(app.SelectionvolumeTab);
            app.Volumefichier3SliderLabel.Text = 'Volume fichier 3';
            app.Volumefichier3Slider = uislider(app.SelectionvolumeTab);
            app.TextArea = uitextarea(app.SelectionvolumeTab);
            app.TextArea.Value = {'Pour chaque canal, selectionnez le volume souhaité du fichier associé. Appuyez sur le bouton appliquer pour appliquer la configuration sonore.'; 'Le changement de canal s''effectue automatiquement et il est affiché dans l''encadré nommé canal.'};
            app.SignaldesortieTab = uitab(app.TabGroup);
            app.SignaldesortieTab.Title = 'Signal de sortie';
            app.UIAxes3 = uiaxes(app.SignaldesortieTab);
            title(app.UIAxes3, 'Temporel')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.SignaldesortieTab);
            title(app.UIAxes3_2, 'Spectre')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.JouerButton = uibutton(app.SignaldesortieTab, 'push');
            app.JouerButton.ButtonPushedFcn = createCallbackFcn(app, @JouerButtonPushed, true);
            app.JouerButton.Enable = 'off';
            app.JouerButton.Text = 'Jouer';
            app.ArreterButton = uibutton(app.SignaldesortieTab, 'push');
            app.ArreterButton.ButtonPushedFcn = createCallbackFcn(app, @ArreterButtonPushed, true);
            app.ArreterButton.Text = 'Arreter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mixeur

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end