classdef App_modulation_Jeanson_Delevaux < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Reprsentationdunsignalmodule  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        ChoixParametres               matlab.ui.container.Tab
        FreqCoupure                   matlab.ui.control.NumericEditField
        FrquencedecoupureLabel        matlab.ui.control.Label
        OrdreFiltre                   matlab.ui.control.NumericEditField
        OrdrefiltreEditFieldLabel     matlab.ui.control.Label
        TypeFiltre                    matlab.ui.control.DropDown
        TypedefiltreappliquDropDown_2Label_4  matlab.ui.control.Label
        NomFiltre                     matlab.ui.control.DropDown
        TypedefiltreappliquDropDown_2Label_3  matlab.ui.control.Label
        FreqSignal                    matlab.ui.control.NumericEditField
        FrquencedusignalLabel         matlab.ui.control.Label
        RepresenterSignal             matlab.ui.control.Button
        PuissanceBP                   matlab.ui.control.NumericEditField
        PuissancedelabandepassanteEditFieldLabel  matlab.ui.control.Label
        AmpBruitBlanc                 matlab.ui.control.NumericEditField
        AmplitudedubruitblancEditFieldLabel  matlab.ui.control.Label
        TypeModulation                matlab.ui.control.DropDown
        TypedemodulationappliquLabel  matlab.ui.control.Label
        AmpSignal                     matlab.ui.control.NumericEditField
        AmplitudedusignalEditFieldLabel  matlab.ui.control.Label
        PhaseSignal                   matlab.ui.control.NumericEditField
        PhasedusignalEditFieldLabel   matlab.ui.control.Label
        RepTemp                       matlab.ui.container.Tab
        AxeTemp                       matlab.ui.control.UIAxes
        RepFreq                       matlab.ui.container.Tab
        AxeFreq                       matlab.ui.control.UIAxes
        GabaritTab                    matlab.ui.container.Tab
        AxeGabaritPhase               matlab.ui.control.UIAxes
        AxeGabaritGain                matlab.ui.control.UIAxes
        ComparaisonSignaux            matlab.ui.container.Tab
        CompTemp                      matlab.ui.control.UIAxes
        CompFreq                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RepresenterSignal
        function RepresenterSignalPushed(app, event)
f=app.FreqSignal.Value;
fp=10*f;
fe=10*fp;

t = 0:1/fe:1;

signal = app.AmpSignal.Value.*sin(t*2*pi*f + app.PhaseSignal.Value);  % le sinus est controlé en amplitude et phase en fonction du temps 

fourier = fft(signal);
ly = length(fourier); % Taille du vecteur y
freqfft = (-ly/2:ly/2-1)/ly*fe;%pour eviter repliement

if (app.TypeModulation.Value == "Frequence")
    modulateSignal = modulate(signal,fp,fe,'fm');
end

if (app.TypeModulation.Value == "Amplitude")
    modulateSignal = cos(2*pi*fp*t).*signal;
end

if (app.TypeModulation.Value == "Phase")
    modulateSignal = modulate(signal,fp,fe,'pm');
end

%ajout du bruit blanc

modulateSignalBB = modulateSignal + app.AmpBruitBlanc.Value*randn(size(t));

%fourier effectuer apres l'ajout du bruit
fourierModulateSignalBB = fft(modulateSignalBB);
fourierCenterModulateBB = fftshift(fourierModulateSignalBB);

plot(app.AxeTemp,t,modulateSignalBB,'b',t,modulateSignal,'r',t,signal,'c');  % représentation temporelle du signal
legend(app.AxeTemp,["Signal modulé avec bruit blanc","Signal modulé","Signal"]);

stem(app.AxeFreq,freqfft,abs(fourierCenterModulateBB));  % représentation fréquentielle du signal
legend(app.AxeFreq,"Signal modulé avec bruit blanc");


% création du filtre

BandPassUtilisateurDB = pow2db(app.PuissanceBP.Value);
n=app.OrdreFiltre.Value;
frqBandePassante = app.FreqCoupure.Value/fe;

if (app.NomFiltre.Value == "Butterworth")
    if (app.TypeFiltre.Value == "Passe bas")
        [numerateur,denominateur] = butter(n,frqBandePassante,"low");
    end
    if (app.TypeFiltre.Value == "Passe bande")
        [numerateur,denominateur] = butter(n,[0.5-frqBandePassante/2,0.5+frqBandePassante/2],"bandpass");
    end
end
if (app.NomFiltre.Value == "Bessel (passe bas uniquement)")
    [numerateur,denominateur] = besself(n,frqBandePassante,"low");
end
if (app.NomFiltre.Value == "Tchebychev")
    if (app.TypeFiltre.Value == "Passe bas")
        [numerateur,denominateur] = cheby1(n,BandPassUtilisateurDB,frqBandePassante,"low");
    end
    if (app.TypeFiltre.Value == "Passe bande")
        [numerateur,denominateur] = cheby1(n,BandPassUtilisateurDB,[0.5-frqBandePassante/2,0.5+frqBandePassante/2],"bandpass");
    end
end


% application du filtre

filterSignal = filter(numerateur,denominateur,modulateSignalBB);
fourierFilterSignal = fft(filterSignal);
fourierCenterFilterSignal = fftshift(fourierFilterSignal);


% gabarit

gabarit = tf(numerateur,denominateur);
[ampGab,phaseGab,freqGab] = bode(gabarit);
ampGabDB = 20*log10(ampGab);

plot(app.AxeGabaritGain,freqGab,ampGabDB(1,:));
plot(app.AxeGabaritPhase,freqGab,phaseGab(1,:));


% représentation du signal filtré

plot(app.CompFreq,freqfft,abs(fourierCenterModulateBB),'b',freqfft,abs(fourierCenterFilterSignal),'m'); % représentation fréquentielle du signal filtré
legend(app.CompFreq,["Signal modulé avec bruit blanc","Signal filtré"]);
plot(app.CompTemp,t,modulateSignalBB,'b',t,filterSignal,'m'); % représentation temporelle du signal filtré

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ChoixParametres = uitab(app.TabGroup);
            app.ChoixParametres.Title = 'Choix des paramètres';
            app.PhasedusignalEditFieldLabel = uilabel(app.ChoixParametres);
            app.PhasedusignalEditFieldLabel.Text = 'Phase du signal';
            app.PhaseSignal = uieditfield(app.ChoixParametres, 'numeric');
            app.AmplitudedusignalEditFieldLabel = uilabel(app.ChoixParametres);
            app.AmplitudedusignalEditFieldLabel.Text = 'Amplitude du signal';
            app.AmpSignal = uieditfield(app.ChoixParametres, 'numeric');
            app.AmpSignal.Value = 4;
            app.TypedemodulationappliquLabel = uilabel(app.ChoixParametres);
            app.TypedemodulationappliquLabel.Text = 'Type de modulation appliqué';
            app.TypeModulation = uidropdown(app.ChoixParametres);
            app.TypeModulation.Items = {'Amplitude', 'Phase', 'Frequence'};
            app.TypeModulation.Value = 'Amplitude';
            app.AmplitudedubruitblancEditFieldLabel = uilabel(app.ChoixParametres);
            app.AmplitudedubruitblancEditFieldLabel.Text = 'Amplitude du bruit blanc';
            app.AmpBruitBlanc = uieditfield(app.ChoixParametres, 'numeric');
            app.AmpBruitBlanc.Value = 3;
            app.PuissancedelabandepassanteEditFieldLabel = uilabel(app.ChoixParametres);
            app.PuissancedelabandepassanteEditFieldLabel.Text = 'Puissance de la bande passante';
            app.PuissanceBP = uieditfield(app.ChoixParametres, 'numeric');
            app.PuissanceBP.Value = 50;
            app.RepresenterSignal = uibutton(app.ChoixParametres, 'push');
            app.RepresenterSignal.ButtonPushedFcn = createCallbackFcn(app, @RepresenterSignalPushed, true);
            app.RepresenterSignal.Text = 'Représenter signal';
            app.FrquencedusignalLabel = uilabel(app.ChoixParametres);
            app.FrquencedusignalLabel.Text = 'Fréquence du signal';
            app.FreqSignal = uieditfield(app.ChoixParametres, 'numeric');
            app.FreqSignal.Value = 250;
            app.TypedefiltreappliquDropDown_2Label_3 = uilabel(app.ChoixParametres);
            app.TypedefiltreappliquDropDown_2Label_3.Text = 'Nom du filtre appliqué';
            app.NomFiltre = uidropdown(app.ChoixParametres);
            app.NomFiltre.Items = {'Tchebychev', 'Butterworth', 'Bessel (passe bas uniquement)'};
            app.NomFiltre.Value = 'Butterworth';
            app.TypedefiltreappliquDropDown_2Label_4 = uilabel(app.ChoixParametres);
            app.TypedefiltreappliquDropDown_2Label_4.Text = 'Type de filtre appliqué';
            app.TypeFiltre = uidropdown(app.ChoixParametres);
            app.TypeFiltre.Items = {'Passe bas', 'Passe bande', ''};
            app.TypeFiltre.Value = 'Passe bas';
            app.OrdrefiltreEditFieldLabel = uilabel(app.ChoixParametres);
            app.OrdrefiltreEditFieldLabel.Text = 'Ordre filtre';
            app.OrdreFiltre = uieditfield(app.ChoixParametres, 'numeric');
            app.OrdreFiltre.Value = 3;
            app.FrquencedecoupureLabel = uilabel(app.ChoixParametres);
            app.FrquencedecoupureLabel.Text = 'Fréquence de coupure';
            app.FreqCoupure = uieditfield(app.ChoixParametres, 'numeric');
            app.FreqCoupure.Value = 200;
            app.RepTemp = uitab(app.TabGroup);
            app.RepTemp.Title = 'Représentation temporelle';
            app.AxeTemp = uiaxes(app.RepTemp);
            title(app.AxeTemp, 'Title')
            xlabel(app.AxeTemp, 'Temps')
            ylabel(app.AxeTemp, 'Signal')
            zlabel(app.AxeTemp, 'Z')
            app.RepFreq = uitab(app.TabGroup);
            app.RepFreq.Title = 'Représentation fréquentielle';
            app.AxeFreq = uiaxes(app.RepFreq);
            title(app.AxeFreq, 'Title')
            xlabel(app.AxeFreq, 'Fréquence')
            ylabel(app.AxeFreq, 'Signal')
            zlabel(app.AxeFreq, 'Z')
            app.GabaritTab = uitab(app.TabGroup);
            app.GabaritTab.Title = 'Gabarit';
            app.AxeGabaritGain = uiaxes(app.GabaritTab);
            title(app.AxeGabaritGain, 'Gain')
            xlabel(app.AxeGabaritGain, 'Fréquence')
            ylabel(app.AxeGabaritGain, 'Y')
            zlabel(app.AxeGabaritGain, 'Z')
            app.AxeGabaritPhase = uiaxes(app.GabaritTab);
            title(app.AxeGabaritPhase, 'Phase')
            xlabel(app.AxeGabaritPhase, 'Fréquence')
            ylabel(app.AxeGabaritPhase, 'Y')
            zlabel(app.AxeGabaritPhase, 'Z')
            app.ComparaisonSignaux = uitab(app.TabGroup);
            app.ComparaisonSignaux.Title = 'Comparaison signaux';
            app.CompFreq = uiaxes(app.ComparaisonSignaux);
            title(app.CompFreq, 'Comparaison fréquentielle')
            xlabel(app.CompFreq, 'Fréquence')
            ylabel(app.CompFreq, 'Signal')
            zlabel(app.CompFreq, 'Z')
            app.CompTemp = uiaxes(app.ComparaisonSignaux);
            title(app.CompTemp, 'Comparaison temporelle')
            xlabel(app.CompTemp, 'Temps')
            ylabel(app.CompTemp, 'Signal')
            zlabel(app.CompTemp, 'Z')
            app.Reprsentationdunsignalmodule = uilabel(app.UIFigure);
            app.Reprsentationdunsignalmodule.Text = 'Représentation d''un signal modulé';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_modulation_Jeanson_Delevaux

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end