classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TextArea                    matlab.ui.control.TextArea
        SelectionnerunepuissanceendBavantlefichierEditField  matlab.ui.control.NumericEditField
        SelectionnerunepuissanceendBavantlefichierEditFieldLabel  matlab.ui.control.Label
        InstructionTextArea         matlab.ui.control.TextArea
        InstructionLabel            matlab.ui.control.Label
        JouerButton                 matlab.ui.control.Button
        NomdufichierEditField       matlab.ui.control.EditField
        NomdufichierEditFieldLabel  matlab.ui.control.Label
        ChercherfichierButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        y
        Fs % permet d'utiliser les variable partout
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChercherfichierButton
        function ChercherfichierButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.wav');

            if filename == 0
                app.y = [];
                app.Fs = [];
            else
                info = audioinfo(fullfile(pathname, filename));
                [app.y,app.Fs] = audioread(fullfile(pathname, filename));
            end
            app.NomdufichierEditField.Value= filename;
            app.JouerButton.Enable = "on"; %permet d'activer le bouton qui est désactivé au demarrage


            t = 0:seconds(1/app.Fs):seconds(info.Duration);
            t = t(1:end-1);

            figure
            affichageaudio = tiledlayout(1,info.NumChannels); %definit le nombre de diagramme
            title(affichageaudio,"Affichage des différents canaux")
            for i= 1:info.NumChannels
                nexttile
                plot(t,app.y(:,i))
                xlabel('Temps')
                ylabel('Signal audio')
                title("Canal "+i)
            end

            %Fourier + puissance + bande passante
            figure
            affichageaudio = tiledlayout(info.NumChannels,2); %definit le nombre de diagramme
            title(affichageaudio,"Affichage des transformées de Fourier et des diagramme de puissance")
            for i= 1:info.NumChannels
                nexttile
                FOURIER=fft(app.y(:,i));
                ly = length(FOURIER); % Taille du vecteur y
                L= (0:ly-1)/ly*app.Fs;
                z = fftshift(FOURIER); % Réorganisation de la FFT
                f = (-ly/2:ly/2-1)/ly*app.Fs;
                stem(f,abs(z))
                xlabel('Temps')
                ylabel('Amplitude')
                title("Fourier canal " + i)

                nexttile
                spectrepuissance=10*log((abs(z).^2)/info.Duration);
                plot(f,spectrepuissance)
                xlabel('Temps')
                ylabel('Puissance en dB')
                title("Puissance canal " + i)

                %bande passante
                [p,S] = polyfit(f,spectrepuissance,10);
                y_fit= polyval(p,f,S);

                BP = f(find(abs(y_fit-app.SelectionnerunepuissanceendBavantlefichierEditField.Value)<0.0002) );
                texteActuel = app.TextArea.Value;%pour ajouter du nouveau texte
                texteNouveau = "Canal "+i+" : "+ round(BP(1)) + " à "+ round (BP(end));
                app.TextArea.Value = [texteActuel; texteNouveau];

            end
        end

        % Button pushed function: JouerButton
        function JouerButtonPushed(app, event)
            sound(app.y,app.Fs)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChercherfichierButton = uibutton(app.UIFigure, 'push');
            app.ChercherfichierButton.ButtonPushedFcn = createCallbackFcn(app, @ChercherfichierButtonPushed, true);
            app.ChercherfichierButton.Text = 'Chercher fichier';
            app.NomdufichierEditFieldLabel = uilabel(app.UIFigure);
            app.NomdufichierEditFieldLabel.Text = 'Nom du fichier';
            app.NomdufichierEditField = uieditfield(app.UIFigure, 'text');
            app.JouerButton = uibutton(app.UIFigure, 'push');
            app.JouerButton.ButtonPushedFcn = createCallbackFcn(app, @JouerButtonPushed, true);
            app.JouerButton.Enable = 'off';
            app.JouerButton.Text = 'Jouer';
            app.InstructionLabel = uilabel(app.UIFigure);
            app.InstructionLabel.Text = 'Instruction :';
            app.InstructionTextArea = uitextarea(app.UIFigure);
            app.InstructionTextArea.Value = {'Sélectionner un fichier .wav à l''aide du bouton "Chercher un fichier".'; 'Des fentre vont s''ouvrir contenant la représentation du signal pour chaque canal du fichier, le diagramme de sa transformé de Fourier et son diagramme de puissance.'; 'Vous pouvez jouer la musique une fois un fichier sélectionné à l''aide du bouton "Jouer".'; 'Vous pouvez sélectionner une puissance en dB et voir la bande passante associée à chaque canal. Attention d''effectuer cette opération avant le choix du fichier.'};
            app.SelectionnerunepuissanceendBavantlefichierEditFieldLabel = uilabel(app.UIFigure);
            app.SelectionnerunepuissanceendBavantlefichierEditFieldLabel.Text = 'Selectionner une puissance en dB (avant le fichier)';
            app.SelectionnerunepuissanceendBavantlefichierEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectionnerunepuissanceendBavantlefichierEditField.Value = -3;
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Les bandes passante en Hz pour les différents canaux :'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end